classdef carInUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Display      matlab.ui.control.EditField
        carLable     matlab.ui.control.Label
        OpenButton   matlab.ui.control.Button
        CloseButton  matlab.ui.control.Button
        GoF3         matlab.ui.control.StateButton
        GoF2         matlab.ui.control.StateButton
        GoF1         matlab.ui.control.StateButton
        GoBasement   matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        floor
        side
        controller
        dState
        doorSafe
    end
    
    methods (Access = public)
        
        function create(app,side) % HIDE GoBasement BUTTON FOR RIGHT CAR
            app.side = side;
            if side == 0
                app.GoBasement.Visible = 'off';
                app.carLable.Text = '#D';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.doorSafe = true;
            app.dState = false;
            app.floor = 1.0;
        end

        % Value changed function: GoBasement
        function sendGoBasement(app, event)
            app.controller.msg(end+1) = 3;
        end

        % Value changed function: GoF1
        function sendGoFloor1(app, event)
            switch app.side
                case 1
                    app.controller.msg(end+1) = 4;
                case 0
                    app.controller.msg(end+1) = -4;
            end
        end

        % Value changed function: GoF2
        function sendGoFloor2(app, event)
            switch app.side
                case 1
                    app.controller.msg(end+1) = 5;
                case 0
                    app.controller.msg(end+1) = -5;
            end
        end

        % Value changed function: GoF3
        function sendGoFloor3(app, event)
            switch app.side
                case 1
                    app.controller.msg(end+1) = 6;
                case 0
                    app.controller.msg(end+1) = -6;
            end
        end

        % Button pushed function: CloseButton
        function sendCloseButton(app, event)
            if app.doorSafe % Occlusion detection
                if app.floor == fix(app.floor)
                    app.controller.moveDoor(app.floor, app.side, false);
                end
            end
        end

        % Button pushed function: OpenButton
        function sendOpenButton(app, event)
            if app.doorSafe && app.floor == fix(app.floor)
                if app.side == 1 && app.controller.ModleUI.cS==0 
                    app.controller.moveDoor(app.floor, 1, true)
                elseif app.side == 0 && app.controller.ModleUI.dS==0
                    app.controller.moveDoor(app.floor, 0, true)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Display = uieditfield(app.UIFigure, 'text');
            app.Display.Value = '1';
            app.carLable = uilabel(app.UIFigure);
            app.carLable.Text = '#C';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @sendOpenButton, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @sendCloseButton, true);
            app.CloseButton.Text = 'Close';
            app.GoF3 = uibutton(app.UIFigure, 'state');
            app.GoF3.ValueChangedFcn = createCallbackFcn(app, @sendGoFloor3, true);
            app.GoF3.Text = '3';
            app.GoF2 = uibutton(app.UIFigure, 'state');
            app.GoF2.ValueChangedFcn = createCallbackFcn(app, @sendGoFloor2, true);
            app.GoF2.Text = '2';
            app.GoF1 = uibutton(app.UIFigure, 'state');
            app.GoF1.ValueChangedFcn = createCallbackFcn(app, @sendGoFloor1, true);
            app.GoF1.Text = '1';
            app.GoBasement = uibutton(app.UIFigure, 'state');
            app.GoBasement.ValueChangedFcn = createCallbackFcn(app, @sendGoBasement, true);
            app.GoBasement.Text = '-1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = carInUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end