classdef floorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        carInsideR  matlab.ui.control.TextArea
        carInsideL  matlab.ui.control.TextArea
        RRDoor      matlab.ui.control.TextArea
        RLDoor      matlab.ui.control.TextArea
        dwButton    matlab.ui.control.StateButton
        upButton    matlab.ui.control.StateButton
        RightFloor  matlab.ui.control.EditField
        LeftFloor   matlab.ui.control.EditField
        Floor       matlab.ui.control.EditField
        LRDoor      matlab.ui.control.TextArea
        LLDoor      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        floorNum % Description
        controller
        lDoorMoveState
        rDoorMoveState
    end

    methods (Access = public)
        
        function create(app,floorNum)
            app.floorNum = floorNum;
            app.Floor.Value = ['Floor' num2str(floorNum)];
            if floorNum == -1
                app.Floor.Value = 'basement';
                app.RightFloor.Visible = 'off';
                app.carInsideR.Visible = 'off';
                app.RRDoor.Visible = 'off';
                app.RLDoor.Visible = 'off';
                app.dwButton.Visible = 'off';
            elseif floorNum == 3
                app.upButton.Visible = 'off';
            end
        end
        
        function moveDoor(app, side, open) % how door position changed is made here
            if side==1 %CONFIRM DOOR MOVEMENT DIRECTION
                if open
                    app.controller.LCprocessing = true;
                    if app.lDoorMoveState == -1 % COMMAND DIRECTION IS OPPOSITE TO DOOR MOVEING DIRECTION
                        return
                    end
                    app.lDoorMoveState = 1;
                else
                    if app.lDoorMoveState == 1
                        return
                    end
                    app.lDoorMoveState = -1;
                end
            else
                if open
                    app.controller.RCprocessing = false;
                    if app.rDoorMoveState == -1
                        return
                    end
                    app.rDoorMoveState = 1;
                else
                    if app.rDoorMoveState == 1
                        return
                    end
                    app.rDoorMoveState = -1;
                end
            end
            t = timer;
            t.ExecutionMode='fixedRate';
            t.Period = 0.02;
            t.TimerFcn = @updatePos;
            start(t);
            function updatePos(~,~,~)
                if side == 1 %LEFT SIDE
                    if open == true % OPEN DOOR
                        if app.LLDoor.Position(1) <= 12 || app.LRDoor.Position(1) >= 308
                            app.lDoorMoveState = 0; % OPEN FINISHED
                            pause(1.5);
                            app.moveDoor(1, false);
                            stop(t);
                            delete(t);
                            clear;
                            return
                        end
                        app.LLDoor.Position(1) = app.LLDoor.Position(1) - 1;
                        app.LRDoor.Position(1) = app.LRDoor.Position(1) + 1;
                    else % CLOSE DOOR
                        if app.LLDoor.Position(1) >= 110 || app.LRDoor.Position(1) <= 210
                            app.lDoorMoveState = 0; % CLOSE FINISHED
                            app.controller.LCprocessing = false;
                            stop(t);
                            delete(t);
                            clear;
                            return
                        end
                        app.LLDoor.Position(1) = app.LLDoor.Position(1) + 1;
                        app.LRDoor.Position(1) = app.LRDoor.Position(1) - 1;
                    end
                else % RIGHT
                    if open == true % OPEN RIGHT DOOR
                        if app.RLDoor.Position(1) <= 502 || app.RRDoor.Position(1) >= 798
                            app.rDoorMoveState = 0; % OPEN FINISHED
                            pause(1.5);
                            app.moveDoor(0, false);
                            stop(t);
                            delete(t);
                            clear;
                            return
                        end
                        app.RLDoor.Position(1) = app.RLDoor.Position(1) - 1;
                        app.RRDoor.Position(1) = app.RRDoor.Position(1) + 1;
                    else % CLOSE RIGHT DOOR
                        if app.RLDoor.Position(1) >= 600 || app.RRDoor.Position(1) <= 700
                            app.rDoorMoveState = 0; % CLOSE FINISHED
                            app.controller.RCprocessing = false;
                            stop(t);
                            delete(t);
                            clear;
                            return
                        end
                        app.RLDoor.Position(1) = app.RLDoor.Position(1) + 1;
                        app.RRDoor.Position(1) = app.RRDoor.Position(1) - 1;
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: upButton
        function sendUpButton(app, event)
            switch app.floorNum
                case -1
                    app.controller.msg(end+1) = 0;
                case 1
                    app.controller.msg(end+1) = 1;
                case 2
                    app.controller.msg(end+1) = 2;
            end
        end

        % Value changed function: dwButton
        function sendDwButton(app, event)
            switch app.floorNum
                case 1
                    app.controller.msg(end+1) = -1;
                case 2
                    app.controller.msg(end+1) = -2;
                case 3
                    app.controller.msg(end+1) = -3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.carInsideR = uitextarea(app.UIFigure);
            app.carInsideL = uitextarea(app.UIFigure);
            app.RRDoor = uitextarea(app.UIFigure);
            app.RLDoor = uitextarea(app.UIFigure);
            app.dwButton = uibutton(app.UIFigure, 'state');
            app.dwButton.ValueChangedFcn = createCallbackFcn(app, @sendDwButton, true);
            app.dwButton.Text = '↓';
            app.upButton = uibutton(app.UIFigure, 'state');
            app.upButton.ValueChangedFcn = createCallbackFcn(app, @sendUpButton, true);
            app.upButton.Text = '↑';
            app.RightFloor = uieditfield(app.UIFigure, 'text');
            app.RightFloor.Value = '1';
            app.LeftFloor = uieditfield(app.UIFigure, 'text');
            app.LeftFloor.Value = '1';
            app.Floor = uieditfield(app.UIFigure, 'text');
            app.Floor.Value = 'Floor1';
            app.LRDoor = uitextarea(app.UIFigure);
            app.LLDoor = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = floorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end