classdef modelUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Basement                 matlab.ui.control.TextArea
        Floor1                   matlab.ui.control.TextArea
        Floor2                   matlab.ui.control.TextArea
        Floor3                   matlab.ui.control.TextArea
        LCar                     matlab.ui.control.TextArea
        RCar                     matlab.ui.control.TextArea
        CSpeedEditFieldLabel     matlab.ui.control.Label
        CSpeed                   matlab.ui.control.EditField
        CHeightLabel             matlab.ui.control.Label
        CHeight                  matlab.ui.control.EditField
        DSpeedEditFieldLabel     matlab.ui.control.Label
        DSpeed                   matlab.ui.control.EditField
        DHeightEditField_2Label  matlab.ui.control.Label
        DHeight                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        controller
        cH
        dH
        cS
        dS
    end
    
    methods (Access = public)
        function move(app, side, floor) % MOVE CAR IN Y AXIS
            up = 1; % CAR MOVE DIRECTION
            orgH = 0; % ORIGN CAR HEIGHT
            decTime = 0.0; % THE TIME THAT CAR START DECELERATE
            if side == 1
                orgH = round(app.cH); % INITIALIZE orgH
                if orgH == floor*3-3 % SAME FLOOR, JUST OPEN DOOR
                    app.controller.moveDoor(floor, side, 1);
                    return
                elseif orgH > floor*3-3 % CAR HIGHER THAN FLOOR, DIRECTION IS DOWN
                    up = -1;
                end
            else
                orgH = round(app.dH);
                if orgH == floor*3-3
                    app.controller.moveDoor(floor, side, 1);
                    return
                elseif orgH > floor*3-3
                    up = -1;
                end
            end
            switch abs(orgH-floor*3+3) % DISTANCE THAT CAR RUNS
                case 3
                    decTime = 2; % CAR RUNS 3m, DECELERATE AT 2s
                case 6
                    decTime = 4; % CAR RUNS 6m, DECELERATE AT 4s
                case 9
                    decTime = 6; % CAR RUNS 9m, DECELERATE AT 6s
            end
            time = 0.0; %THE TIME CAR HAS RUN
            t = timer;
            t.ExecutionMode = 'fixedRate';
            t.Period = 0.1;
            t.TimerFcn = @updatePos;
            start(t);
            function updatePos(~,~,~)
                digits(3); % UNIFORM DIGITS OF TEXTAREA DISPALY
                time = time + t.Period;
                if side == 1 % LEFT #C
                    if time <= 1.0 % UNIFORMLY ACCELERATED LINEAR MOTION
                        app.cH = 0.75*time*time*up + orgH;
                        app.cS = 1.5*time;
                    elseif time <= decTime % UNIFORMLY LINEAR MOTION
                        app.cH = (0.75+(time-1)*1.5)*up + orgH;
                    elseif time <= decTime+1 % UNIFORMLY DECELERATED LINEAR MOTION
                        app.cH = (0.75+(decTime-1)*1.5+1.5*(time-decTime)-0.75*(time-decTime)*(time-decTime))*up + orgH;
                        app.cS = 1.5-1.5*(time-decTime);
                    else % FINAL STATE
                        app.cH = floor*3-3;
                        app.cS = 0;
                        app.CHeight.Value = string(roundn(app.cH, -2))+' m';
                        app.CSpeed.Value = string(roundn(app.cS, -2))+' m/s';
                        app.LCar.Position(2) = floor*100;
                        app.controller.moveDoor(floor, 1, true); % OPEN DOOR WHEN ARRIVED
                        switch floor  % RESET LEFT CAR GoF.. BUTTON
                            case {-1, 0}
                                app.controller.CarUIl.GoBasement.Value = false;
                            case 1
                                app.controller.CarUIl.GoF1.Value = false;
                            case 2
                                app.controller.CarUIl.GoF2.Value = false;
                            case 3
                                app.controller.CarUIl.GoF3.Value = false;
                        end
                        stop(t);
                        delete(t);
                        clear;
                        return
                    end
                    % EVERY PERIOD, UPDATE VALUE
                    app.CHeight.Value = string(roundn(app.cH,-2))+' m';
                    app.CSpeed.Value = string(roundn(app.cS,-2))+' m/s';
                    app.LCar.Position(2) = app.cH*100/3 + 100;
                    app.controller.CarUIl.floor = round(app.cH/3)+1;
                    if app.controller.CarUIl.floor == 0
                        app.controller.CarUIl.floor = -1;
                    end
                else % RIGHT #D
                    if time <= 1.0
                        app.dH = 0.75*time*time*up + orgH;
                        app.dS = 1.5*time;
                    elseif time <= decTime
                        app.dH = (0.75+(time-1)*1.5)*up + orgH;
                    elseif time <= decTime+1
                        app.dH = (0.75+(decTime-1)*1.5+1.5*(time-decTime)-0.75*(time-decTime)*(time-decTime))*up + orgH;
                        app.dS = 1.5-1.5*(time-decTime);
                    else % INSURE FINAL STATE
                        app.dH = floor*3-3;
                        app.dS = 0;
                        app.DHeight.Value = string(roundn(app.dH,-2))+' m';
                        app.DSpeed.Value = string(roundn(app.dS,-2))+' m/s';
                        app.RCar.Position(2) = floor*100;
                        app.controller.moveDoor(floor, 0, true);
                        switch floor % RESET RIGHT CAR Gof.. BUTTON
                            case 1
                                app.controller.CarUIr.GoF1.Value = false;
                            case 2
                                app.controller.CarUIr.GoF2.Value = false;
                            case 3
                                app.controller.CarUIr.GoF3.Value = false;
                        end
                        stop(t);
                        delete(t);
                        clear;
                        return;
                    end
                    app.DHeight.Value = string(roundn(app.dH,-2))+' m';
                    app.DSpeed.Value = string(roundn(app.dS,-2))+' m/s';
                    app.RCar.Position(2) = app.dH*100/3 + 100;
                    app.controller.CarUIr.floor = round(app.dH/3)+1;
                end
                app.controller.updateDisp();
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cH = 0.0;
            app.cS = 0.0;
            app.dH = 0.0;
            app.dS = 0.0;
            app.CSpeed.Value = string(app.cS)+' m/s';
            app.CHeight.Value = string(app.cH)+' m';
            app.DSpeed.Value = string(app.dS)+' m/s';
            app.DHeight.Value = string(app.dH)+' m';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.controller.t);
            delete(app.controller.t);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Basement = uitextarea(app.UIFigure);
            app.Basement.Value = {'      base-'; '     ment'};
            app.Floor1 = uitextarea(app.UIFigure);
            app.Floor1.Value = {'floor 1'};
            app.Floor2 = uitextarea(app.UIFigure);
            app.Floor2.Value = {'floor 2'};
            app.Floor3 = uitextarea(app.UIFigure);
            app.Floor3.Value = {'floor 3'};
            app.LCar = uitextarea(app.UIFigure);
            app.LCar.Value = {'#C'};
            app.RCar = uitextarea(app.UIFigure);
            app.RCar.Value = {'#D'};
            app.CSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.CSpeedEditFieldLabel.Text = '#C Speed:';
            app.CSpeed = uieditfield(app.UIFigure, 'text');
            app.CHeightLabel = uilabel(app.UIFigure);
            app.CHeightLabel.Text = '#C Height:';
            app.CHeight = uieditfield(app.UIFigure, 'text');
            app.DSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.DSpeedEditFieldLabel.Text = '#D Speed:';
            app.DSpeed = uieditfield(app.UIFigure, 'text');
            app.DHeightEditField_2Label = uilabel(app.UIFigure);
            app.DHeightEditField_2Label.Text = '#D Height:';
            app.DHeight = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modelUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end